classdef covid_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticsofCovidCasesDeathsUIFigure  matlab.ui.Figure
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        CountryLabel               matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        UIAxes                     matlab.ui.control.UIAxes
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        countrylist
        country
        statelist
        state
        temp
        dates
        cases
        deaths
        average
        averagetitle
        cumulcasesaverage
        cumuldeathsaverage
        dailycasesaverage
        dailydeathsaverage
        showcases
        showdeaths
        showboth
        showcumul
        showdaily
        cumul
        cumulcases
        cumuldeaths
        daily
        dailycases
        dailydeaths
        limits
    end
    
    methods (Access = private)
        
       
        function plotdata(app)
            app.UIAxes.reset;
            hald_window = app.average/2;
            start = floor(hald_window)+1;
            stop = length(app.dates) - (ceil(hald_window)-1);
            inside_range = start:stop;
            partialdates = app.dates(inside_range);
            if app.country == "Global"
                if app.showcases
                    if app.showcumul
                        partialcumulcases = app.cumulcasesaverage(inside_range);
                        bar(app.UIAxes,partialdates,partialcumulcases);
                        ylim(app.UIAxes,[0,max(partialcumulcases)])
                        app.UIAxes.Title.String = "Cumulative Number of Cases in " +app.country+ " ("+app.averagetitle+ "-day mean)";
                    end
                    if app.showdaily
                        partialdailycases = app.dailycasesaverage(inside_range);
                        bar(app.UIAxes,partialdates,partialdailycases);
                        ylim(app.UIAxes,[0,max(partialdailycases)])
                        app.UIAxes.Title.String ="Daily Number of Cases in " + app.country + " (" +app.averagetitle+ "-day mean)";
                    end
                end
                if app.showdeaths
                    if app.showcumul
                        partialcumuldeaths = app.cumuldeathsaverage(inside_range);
                        plot(app.UIAxes,partialdates,partialcumuldeaths);
                        ylim(app.UIAxes,[0,max(partialcumuldeaths)])
                        app.UIAxes.Title.String ="Cumulative Number of Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                    end
                    if app.showdaily
                        partialdailydeaths = app.dailydeathsaverage(inside_range);
                        plot(app.UIAxes,partialdates,partialdailydeaths);
                        ylim(app.UIAxes,[0,max(partialdailydeaths)])
                        app.UIAxes.Title.String ="Daily Number of Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                    end
                end
                if app.showboth
                    if app.showcumul
                        partialcumulcases = app.cumulcasesaverage(inside_range);
                        partialcumuldeaths = app.cumuldeathsaverage(inside_range);
                        yyaxis(app.UIAxes,'left')
                        bar(app.UIAxes,partialdates,partialcumulcases);
                        ylim(app.UIAxes,[0,max(partialcumulcases)])
                        yyaxis(app.UIAxes,'right')
                        plot(app.UIAxes,partialdates,partialcumuldeaths);
                        ylim(app.UIAxes,[0,max(partialcumuldeaths)])
                        app.UIAxes.Title.String ="Cumulative Number of Cases and Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                    end
                    if app.showdaily
                        partialdailycases = app.dailycasesaverage(inside_range);
                        partialdailydeaths = app.dailydeathsaverage(inside_range);
                        yyaxis(app.UIAxes,'left')
                        bar(app.UIAxes,partialdates,partialdailycases);
                        ylim(app.UIAxes,[0,max(partialdailycases)])
                        yyaxis(app.UIAxes,'right')
                        plot(app.UIAxes,partialdates,partialdailydeaths);
                        ylim(app.UIAxes,[0,max(partialdailydeaths)])
                        app.UIAxes.Title.String ="Daily Number of Cases and Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                    end
                end
            end
            
            if app.country ~= "Global"
                if string(app.state) == "All"
                    if app.showcases
                        if app.showcumul
                            partialcumulcases = app.cumulcasesaverage(inside_range);
                            bar(app.UIAxes,partialdates,partialcumulcases);
                            ylim(app.UIAxes,[0,max(partialcumulcases)])
                            app.UIAxes.Title.String = "Cumulative Number of Cases in " +app.country+ " ("+app.averagetitle+ "-day mean)";
                        end
                        if app.showdaily
                            partialdailycases = app.dailycasesaverage(inside_range);
                            bar(app.UIAxes,partialdates,partialdailycases);
                            ylim(app.UIAxes,[0,max(partialdailycases)])
                            app.UIAxes.Title.String ="Daily Number of Cases in " + app.country + " (" +app.averagetitle+ "-day mean)";
                        end
                    end
                    if app.showdeaths
                        if app.showcumul
                            partialcumuldeaths = app.cumuldeathsaverage(inside_range);
                            plot(app.UIAxes,partialdates,partialcumuldeaths);
                            ylim(app.UIAxes,[0,max(partialcumuldeaths)])
                            app.UIAxes.Title.String ="Cumulative Number of Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                        end
                        if app.showdaily
                            partialdailydeaths = app.dailydeathsaverage(inside_range);
                            plot(app.UIAxes,partialdates,partialdailydeaths);
                            ylim(app.UIAxes,[0,max(partialdailydeaths)])
                            app.UIAxes.Title.String ="Daily Number of Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                        end
                    end
                    if app.showboth
                        if app.showcumul
                            partialcumulcases = app.cumulcasesaverage(inside_range);
                            partialcumuldeaths = app.cumuldeathsaverage(inside_range);
                            yyaxis(app.UIAxes,'left')
                            bar(app.UIAxes,partialdates,partialcumulcases);
                            ylim(app.UIAxes,[0,max(partialcumulcases)])
                            yyaxis(app.UIAxes,'right')
                            plot(app.UIAxes,partialdates,partialcumuldeaths);
                            ylim(app.UIAxes,[0,max(partialcumuldeaths)])
                            app.UIAxes.Title.String ="Cumulative Number of Cases and Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                        end
                        if app.showdaily
                            partialdailycases = app.dailycasesaverage(inside_range);
                            partialdailydeaths = app.dailydeathsaverage(inside_range);
                            yyaxis(app.UIAxes,'left')
                            bar(app.UIAxes,partialdates,partialdailycases);
                            ylim(app.UIAxes,[0,max(partialdailycases)])
                            yyaxis(app.UIAxes,'right')
                            plot(app.UIAxes,partialdates,partialdailydeaths);
                            ylim(app.UIAxes,[0,max(partialdailydeaths)])
                            app.UIAxes.Title.String ="Daily Number of Cases and Deathes in " + app.country + " (" +app.averagetitle+ "-day mean)";
                        end
                    end
                end
            
                if string(app.StateorRegionListBox.Value) ~= "All"
                    if app.showcases
                        if app.showcumul
                            partialcumulcases = app.cumulcasesaverage(inside_range);
                            bar(app.UIAxes,partialdates,partialcumulcases);
                            ylim(app.UIAxes,[0,max(partialcumulcases)])
                            app.UIAxes.Title.String = "Cumulative Number of Cases in " +app.country+ " in "+ string(app.state)+" State "+ " ("+app.averagetitle+ "-day mean)";
                        end
                        if app.showdaily
                            partialdailycases = app.dailycasesaverage(inside_range);
                            bar(app.UIAxes,partialdates,partialdailycases);
                            ylim(app.UIAxes,[0,max(partialdailycases)])
                            app.UIAxes.Title.String ="Daily Number of Cases in " + app.country + " in "+ string(app.state)+" State "+  " (" +app.averagetitle+ "-day mean)";
                        end
                    end
                    if app.showdeaths
                        if app.showcumul
                            partialcumuldeaths = app.cumuldeathsaverage(inside_range);
                            plot(app.UIAxes,partialdates,partialcumuldeaths);
                            ylim(app.UIAxes,[0,max(partialcumuldeaths)])
                            app.UIAxes.Title.String ="Cumulative Number of Deathes in " + app.country + " in "+ string(app.state)+" State "+  " (" +app.averagetitle+ "-day mean)";
                        end
                        if app.showdaily
                            partialdailydeaths = app.dailydeathsaverage(inside_range);
                            plot(app.UIAxes,partialdates,partialdailydeaths);
                            ylim(app.UIAxes,[0,max(partialdailydeaths)])
                            app.UIAxes.Title.String ="Daily Number of Deathes in " + app.country + " in "+ string(app.state)+" State "+  " (" +app.averagetitle+ "-day mean)";
                        end
                    end
                    if app.showboth
                        if app.showcumul
                            partialcumulcases = app.cumulcasesaverage(inside_range);
                            partialcumuldeaths = app.cumuldeathsaverage(inside_range);
                            yyaxis(app.UIAxes,'left')
                            bar(app.UIAxes,partialdates,partialcumulcases);
                            ylim(app.UIAxes,[0,max(partialcumulcases)])
                            yyaxis(app.UIAxes,'right')
                            plot(app.UIAxes,partialdates,partialcumuldeaths);
                            ylim(app.UIAxes,[0,max(partialcumuldeaths)])
                            app.UIAxes.Title.String ="Cumulative Number of Cases and Deathes in " + app.country + " in "+ string(app.state)+" State "+ " (" +app.averagetitle+ "-day mean)";
                        end
                        if app.showdaily
                            partialdailycases = app.dailycasesaverage(inside_range);
                            partialdailydeaths = app.dailydeathsaverage(inside_range);
                            yyaxis(app.UIAxes,'left')
                            bar(app.UIAxes,partialdates,partialdailycases);
                            ylim(app.UIAxes,[0,max(partialdailycases)])
                            yyaxis(app.UIAxes,'right')
                            plot(app.UIAxes,partialdates,partialdailydeaths);
                            ylim(app.UIAxes,[0,max(partialdailydeaths)])
                            app.UIAxes.Title.String ="Daily Number of Cases and Deathes in " + app.country + " in "+ string(app.state)+" State "+ " (" +app.averagetitle+ "-day mean)";
                        end
                    end
                end
            end
        end
        
        function calcaverage(app)
            if app.showcumul
                app.cumulcasesaverage = movmean(app.cumulcases,app.average);
                app.cumuldeathsaverage = movmean(app.cumuldeaths,app.average);
            end
            if app.showdaily
                app.dailycasesaverage = movmean(app.dailycases,app.average);
                app.dailydeathsaverage = movmean(app.dailydeaths,app.average);
            end
            app.plotdata();
        end
        
        function default(app)
            app.BothButton.Value = false;
            app.CumulativeButton.Value = true;
            app.CasesButton.Value = true;
            app.DeathsButton.Value = false;
            app.DailyButton.Value = false;
            app.CountryListBox.Value = app.countrylist(1);
            app.StateorRegionListBox.Value = app.statelist(1);
            
            app.average = app.AveragedofdaysSlider.Value;
            app.averagetitle = string(app.average);
            app.showcases = app.CasesButton.Value;
            app.showdeaths = app.DeathsButton.Value;
            app.showboth = app.BothButton.Value;
            app.showcumul = app.CumulativeButton.Value;
            app.showdaily = app.DailyButton.Value;
            app.country = app.CountryListBox.Value;
            app.country = string(app.country);
            app.state = app.StateorRegionListBox.Value;
            app.state = string(app.state);
            
            app.datafigure();
        end
        
        function datafigure(app)
            load covid_data.mat covid_data;
            app.cumul = {};
            app.cumulcases = {};
            app.cumuldeaths = {};
            app.daily = {};
            app.dailycases = {};
            app.dailydeaths = {};
            if app.country == "Global"
                if app.showcumul
                    app.cumul= sum(cell2mat(covid_data(2:end,3:end)),1);
                    app.cumulcases = app.cumul(1,1:2:end);
                    app.cumuldeaths = app.cumul(1,2:2:end);
                end
                
                if app.showdaily
                   app.daily = sum(cell2mat(covid_data(2:end,3:end)),1);
                   for i = length(app.daily) :-1: 3
                       app.daily(1,i) = app.daily(1,i) - app.daily(1,i-2);
                   end
                   app.dailycases = app.daily(1,1:2:end);
                   app.dailydeaths = app.daily(1,2:2:end);
                end
            end
            
            if app.country ~= "Global"
                if string(app.state) == "All"
                    if app.showcumul
                        app.cumul= sum(cell2mat(covid_data(covid_data(2:end,1)==app.country,3:end)),1);
                        app.cumulcases = app.cumul(1,1:2:end);
                        app.cumuldeaths = app.cumul(1,2:2:end);
                    end
                    
                    if app.showdaily
                        app.daily = sum(cell2mat(covid_data(covid_data(2:end,1)==app.country,3:end)),1);
                        for i = length(app.daily) :-1: 3
                            app.daily(1,i) = app.daily(1,i) - app.daily(1,i-2);
                        end
                        app.dailycases = app.daily(1,1:2:end);
                        app.dailydeaths = app.daily(1,2:2:end);
                    end
                end
                
                if string(app.state) ~= "All"
                    if app.showcumul
                        app.cumul= covid_data(covid_data(2:end,1)==app.country,2:end);
                        app.cumul = sum(cell2mat(app.cumul(app.cumul(1:end,1)==app.state,2:end)),1);
                        app.cumulcases = app.cumul(1,1:2:end);
                        app.cumuldeaths = app.cumul(1,2:2:end);
                    end
                    
                    if app.showdaily
                        app.daily= covid_data(covid_data(2:end,1)==app.country,2:end);
                        app.daily = sum(cell2mat(app.daily(app.daily(1:end,1)==app.state,2:end)),1);
                        for i = length(app.daily) :-1: 3
                            app.daily(1,i) = app.daily(1,i) - app.daily(1,i-2);
                        end
                        app.dailycases = app.daily(1,1:2:end);
                        app.dailydeaths = app.daily(1,2:2:end);
                    end
                end
            end
            app.calcaverage();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.dates = datetime(datestr(covid_data(1,3:end)));
            app.countrylist= unique(covid_data(2:end,1));
            app.temp = app.countrylist{1,1};
            app.countrylist{1,1} = 'Global';
            for i = 196:-1:3
                app.countrylist{i,1} = app.countrylist{i-1,1};
            end
            app.countrylist{2,1} = app.temp;
            app.CountryListBox.Items = app.countrylist;
            app.statelist{1,1} = 'All';
            app.StateorRegionListBox.Items = app.statelist;
            app.AveragedofdaysSlider.Value = ceil(app.AveragedofdaysSlider.Limits(2)/2);
            app.average = app.AveragedofdaysSlider.Value;
            
            app.default();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            load covid_data.mat covid_data;
            app.country = app.CountryListBox.Value;
            app.country = string(app.country);
            app.statelist = covid_data(covid_data(1:end,1)==app.country,2);
            app.statelist = app.statelist(~cellfun('isempty',app.statelist));
            if ~isempty(app.statelist)
                app.temp = app.statelist{1,1};
                app.statelist{1,1} = 'All';
                for i = length(app.statelist):-1:3
                    app.statelist{i,1} = app.statelist{i-1,1};
                end
                app.statelist{2,1} = app.temp;
            end
            if isempty(app.statelist)
                app.statelist{1,1} = 'All';
            end
            app.StateorRegionListBox.Items = app.statelist;
            app.state = "All";
            app.StateorRegionListBox.Value = app.state;
            
            app.datafigure();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.showcases = app.CasesButton.Value || app.BothButton.Value;
            app.showdeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.showboth = app.BothButton.Value;
            
            app.plotdata();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.showcumul = app.CumulativeButton.Value;
            app.showdaily = app.DailyButton.Value;
            
            app.datafigure();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.state = app.StateorRegionListBox.Value;
            app.state = string(app.state);
            app.datafigure();
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.average = ceil(event.Value);
            app.AveragedofdaysSlider.Value = app.average;
            app.averagetitle = string(app.average);
            
            app.calcaverage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatisticsofCovidCasesDeathsUIFigure = uifigure('Visible', 'off');
            app.StatisticsofCovidCasesDeathsUIFigure.Name = 'Statistics of Covid Cases & Deaths';
            app.DatatoPlotButtonGroup = uibuttongroup(app.StatisticsofCovidCasesDeathsUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.StatisticsofCovidCasesDeathsUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryLabel = uilabel(app.StatisticsofCovidCasesDeathsUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.StatisticsofCovidCasesDeathsUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionListBoxLabel = uilabel(app.StatisticsofCovidCasesDeathsUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.StatisticsofCovidCasesDeathsUIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.UIAxes = uiaxes(app.StatisticsofCovidCasesDeathsUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.AveragedofdaysSliderLabel = uilabel(app.StatisticsofCovidCasesDeathsUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.StatisticsofCovidCasesDeathsUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.StatisticsofCovidCasesDeathsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatisticsofCovidCasesDeathsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticsofCovidCasesDeathsUIFigure)
        end
    end
end